classdef LLE_SIM < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        SELECTHUMANSIZELabel            matlab.ui.control.Label
        kgLabel                         matlab.ui.control.Label
        mLabel                          matlab.ui.control.Label
        kgLabel_2                       matlab.ui.control.Label
        kgLabel_3                       matlab.ui.control.Label
        mLabel_2                        matlab.ui.control.Label
        mLabel_3                        matlab.ui.control.Label
        Button                          matlab.ui.control.Button
        Button_2                        matlab.ui.control.Button
        NominalButton                   matlab.ui.control.Button
        DETACHButton                    matlab.ui.control.Button
        ATTACHButton                    matlab.ui.control.Button
        ATTACHDETACHEXOSKELETONLabel    matlab.ui.control.Label
        PERCENTAGETORQUEREDUCTIONLabel  matlab.ui.control.Label
        Button_3                        matlab.ui.control.Button
        Button_4                        matlab.ui.control.Button
        ThismaytakesometimeLabel        matlab.ui.control.Label
        RunSimulationButton             matlab.ui.control.Button
        RUNLabel                        matlab.ui.control.Label
        CenterPanel                     matlab.ui.container.Panel
        SimulationStoppedLampLabel      matlab.ui.control.Label
        SimulationStoppedLamp           matlab.ui.control.Lamp
        STOPButton                      matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        SELECTEDVARIABLESLabel          matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        RightPanel                      matlab.ui.container.Panel
        PlotExoskeletonJointAnglesButton_2  matlab.ui.control.Button
        PlotExoskeletonJointTorquesButton_2  matlab.ui.control.Button
        PlotHumanJointTorquesButton     matlab.ui.control.Button
        PlotHumanJointAnglesButton      matlab.ui.control.Button
        PlotExoHumanContactForcesButton  matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load_system("Human-Exo Simulink Files\Human_ActiveExo.slx")
            
            msgbox({'                               INSTUCTIONS';...
            ''; ...
            ''; ...
            '1. Select the human sizes from the three options available  ';...
            ''; ...
            '2. Select if the exoskeleton is attached or detached to the ';...
            'human.';...
            ''; ...
            '3. Select the assist the exoskeleton gives to the human from';...
            'the two selections'; ...
            ''; ...
            '4. Run simultion by pressing the big RUN SIMULATION button, ';...
            'this process may take some time due to calculations and sim-';...
            'ulink simulations and can be stopped at any time by pressing';...
            'the stop button in the centre of the app';...
            ''; ...
            '5. graphs can be shown by presssing the selections on the r-';...
            'ight side of the app. The full graphs are only shown if the ';...
            'simulation has ran from start to end without stopping'},'HELP','help')
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {290, '1x', 228};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            [humanModel, humanAnthropometry] = ...
            fnc_ModelCreator('ModelFactory\data\NominalMinus15Human.env');
            assignin('base','humanModel',humanModel);
            assignin('base','humanAnthropometry',humanAnthropometry);
            % Position Files
            set_param('Human_ActiveExo/HUMAN + EXOSKELETON/EXOSKELETON/To File6','commented','on');
            set_param('Human_ActiveExo/HUMAN + EXOSKELETON/EXOSKELETON/To File2','commented','on');
            set_param('Human_ActiveExo/HUMAN + EXOSKELETON/EXOSKELETON/To File5','commented','off');
            % Input Torques
            set_param('Human_ActiveExo/HUMAN + EXOSKELETON/Manual Switch1', 'sw', '0');
            %label 
            app.Label.Text = 'Size -15%';
        end

        % Button pushed function: NominalButton
        function NominalButtonPushed(app, event)
            [humanModel, humanAnthropometry] = ...
            fnc_ModelCreator('ModelFactory\data\NominalHuman.env');
            assignin('base','humanModel',humanModel);
            assignin('base','humanAnthropometry',humanAnthropometry);
            % Position Files
            set_param('Human_ActiveExo/HUMAN + EXOSKELETON/EXOSKELETON/To File6','commented','on');
            set_param('Human_ActiveExo/HUMAN + EXOSKELETON/EXOSKELETON/To File2','commented','off');
            set_param('Human_ActiveExo/HUMAN + EXOSKELETON/EXOSKELETON/To File5','commented','on');
            % Input Torques
            set_param('Human_ActiveExo/HUMAN + EXOSKELETON/Manual Switch', 'sw', '1');
            set_param('Human_ActiveExo/HUMAN + EXOSKELETON/Manual Switch1', 'sw', '1');
            %label 
            app.Label.Text = 'Size Nominal';
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            [humanModel, humanAnthropometry] = ...
            fnc_ModelCreator('ModelFactory\data\NominalPlus15Human.env');
            assignin('base','humanModel',humanModel);
            assignin('base','humanAnthropometry',humanAnthropometry);
            % Position Files
            set_param('Human_ActiveExo/HUMAN + EXOSKELETON/EXOSKELETON/To File6','commented','off');
            set_param('Human_ActiveExo/HUMAN + EXOSKELETON/EXOSKELETON/To File2','commented','on');
            set_param('Human_ActiveExo/HUMAN + EXOSKELETON/EXOSKELETON/To File5','commented','on');
            % Input Torques
            set_param('Human_ActiveExo/HUMAN + EXOSKELETON/Manual Switch', 'sw', '0');
            set_param('Human_ActiveExo/HUMAN + EXOSKELETON/Manual Switch1', 'sw', '1');
            %label 
            app.Label.Text = 'Size +15%';
        end

        % Button pushed function: ATTACHButton
        function ATTACHButtonPushed(app, event)
            set_param('Human_ActiveExo/HUMAN + EXOSKELETON/EXOSKELETON','commented','off');
            %label 
            app.Label_2.Text = 'Exo Attached';
        end

        % Button pushed function: DETACHButton
        function DETACHButtonPushed(app, event)
            set_param('Human_ActiveExo/HUMAN + EXOSKELETON/EXOSKELETON','commented','on');
            set_param('Human_ActiveExo/HUMAN + EXOSKELETON/Lower Body/To File1','commented','on');
            set_param('Human_ActiveExo/HUMAN + EXOSKELETON/Lower Body/To File2','commented','on');
            set_param('Human_ActiveExo/HUMAN + EXOSKELETON/Lower Body/To File6','commented','off');
            %label 
            app.Label_2.Text = 'Exo Detached'; 
            app.Label_3.Text = ' ';
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            % attach exo 
            set_param('Human_ActiveExo/HUMAN + EXOSKELETON/EXOSKELETON','commented','off');
            %change PID switch
            set_param('Human_ActiveExo/HUMAN + EXOSKELETON/EXOSKELETON/Manual Switch', 'sw', '1');
            set_param('Human_ActiveExo/HUMAN + EXOSKELETON/EXOSKELETON/Manual Switch1', 'sw', '1');
            set_param('Human_ActiveExo/HUMAN + EXOSKELETON/EXOSKELETON/Manual Switch2', 'sw', '1');
            % Exo Torque Files
            set_param('Human_ActiveExo/HUMAN + EXOSKELETON/EXOSKELETON/To File3','commented','on');
            set_param('Human_ActiveExo/HUMAN + EXOSKELETON/EXOSKELETON/To File1','commented','off');
            % Human Torque Files
            set_param('Human_ActiveExo/HUMAN + EXOSKELETON/Lower Body/To File1','commented','off');
            set_param('Human_ActiveExo/HUMAN + EXOSKELETON/Lower Body/To File2','commented','on');
            set_param('Human_ActiveExo/HUMAN + EXOSKELETON/Lower Body/To File6','commented','on');
            % Contact Forces
            set_param('Human_ActiveExo/HUMAN + EXOSKELETON/Exo - Human Connections/To File2','commented','off');
            set_param('Human_ActiveExo/HUMAN + EXOSKELETON/Exo - Human Connections/To File3','commented','on');
            set_param('Human_ActiveExo/HUMAN + EXOSKELETON/Exo - Human Connections/To File5','commented','off');
            set_param('Human_ActiveExo/HUMAN + EXOSKELETON/Exo - Human Connections/To File6','commented','on');
            %label
            app.Label_3.Text = 'Assist 60%';
            app.Label_2.Text = 'Exo Attached';
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            % attach exo 
            set_param('Human_ActiveExo/HUMAN + EXOSKELETON/EXOSKELETON','commented','off');
            % Chnage PID Switch
            set_param('Human_ActiveExo/HUMAN + EXOSKELETON/EXOSKELETON/Manual Switch', 'sw', '0');
            set_param('Human_ActiveExo/HUMAN + EXOSKELETON/EXOSKELETON/Manual Switch1', 'sw', '0');
            set_param('Human_ActiveExo/HUMAN + EXOSKELETON/EXOSKELETON/Manual Switch2', 'sw', '0');
            % Exo Torques Files
            set_param('Human_ActiveExo/HUMAN + EXOSKELETON/EXOSKELETON/To File1','commented','on');
            set_param('Human_ActiveExo/HUMAN + EXOSKELETON/EXOSKELETON/To File3','commented','off');
            % Human Torque Files
            set_param('Human_ActiveExo/HUMAN + EXOSKELETON/Lower Body/To File1','commented','on');
            set_param('Human_ActiveExo/HUMAN + EXOSKELETON/Lower Body/To File2','commented','off');
            set_param('Human_ActiveExo/HUMAN + EXOSKELETON/Lower Body/To File6','commented','on');
            % Contact Forces
            set_param('Human_ActiveExo/HUMAN + EXOSKELETON/Exo - Human Connections/To File2','commented','on');
            set_param('Human_ActiveExo/HUMAN + EXOSKELETON/Exo - Human Connections/To File3','commented','off');
            set_param('Human_ActiveExo/HUMAN + EXOSKELETON/Exo - Human Connections/To File5','commented','on');
            set_param('Human_ActiveExo/HUMAN + EXOSKELETON/Exo - Human Connections/To File6','commented','off');
            %label
            app.Label_3.Text = 'Assist 30%';
            app.Label_2.Text = 'Exo Attached';
        end

        % Button pushed function: RunSimulationButton
        function RunSimulationButtonPushed(app, event)
            app.SimulationStoppedLamp.Color = 'green'
            app.SimulationStoppedLampLabel.Text = 'Simulation Running';
            SimulationTime = 6;
            sim('Human_ActiveExo',SimulationTime)
            app.SimulationStoppedLamp.Color = 'red'
            app.SimulationStoppedLampLabel.Text = 'Simulation Stopped';
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            set_param(gcs, 'SimulationCommand', 'stop')
        end

        % Button pushed function: PlotHumanJointAnglesButton
        function PlotHumanJointAnglesButtonPushed(app, event)
            cla(app.UIAxes);
            app.Label_4.Text = ' ';
            
            load('Human Positions\SimHumanPos.mat');

            SimTime = 6;
            X1 = 0:0.001:SimTime;
            
            YMatrix1 = [SimHumanPos(2,:)];
            YMatrix2 = [SimHumanPos(3,:)];
            YMatrix3 = [SimHumanPos(4,:)];
            
            %% Human Model Ankle Torques Vs Mak

            plot(app.UIAxes,YMatrix1);     
            plot(app.UIAxes,YMatrix2);
            plot(app.UIAxes,YMatrix3);
           
            % Create ylabel
            ylabel(app.UIAxes,'Position (Deg)');
            
            % Create xlabel
            xlabel(app.UIAxes,'Time (ms)');  
            
            %Create title
             title(app.UIAxes,'Human Positions',...
                 'FontWeight','bold');  
             
            % Create legend
            legend(app.UIAxes,'Ankle','Knee','Hip');

        end

        % Button pushed function: PlotHumanJointTorquesButton
        function PlotHumanJointTorquesButtonPushed(app, event)
            cla(app.UIAxes);
            app.Label_4.Text = ' ';
            
            a = get_param('Human_ActiveExo/HUMAN + EXOSKELETON/Lower Body/To File6','commented');
            b = get_param('Human_ActiveExo/HUMAN + EXOSKELETON/Lower Body/To File1','commented');
            c = get_param('Human_ActiveExo/HUMAN + EXOSKELETON/Lower Body/To File2','commented');
            
            
            if strcmp(a,'off')
                load('Human Torques\HumanTorquesNoExo.mat')
                HT = HumanTorquesNoExo;
                title(app.UIAxes,'Human No Exo Joint Torques',...
                 'FontWeight','bold');
            elseif strcmp(b,'off')
                load('Human Torques\HumanTorques60.mat')
                HT = HumanTorques60;
                title(app.UIAxes,'Human 60% Assist Joint Torques',...
                 'FontWeight','bold');
            elseif strcmp(c,'off')
                load('Human Torques\HumanTorques30.mat')
                HT = HumanTorques30;
                title(app.UIAxes,'Human 30% Assist Joint Torques',...
                 'FontWeight','bold');
            end
            
            
            YMatrix1 = [HT(2,:)];
            YMatrix2 = [HT(3,:)];
            YMatrix3 = [HT(4,:)];
            
            %% Human Model Ankle Torques Vs Mak

            plot(app.UIAxes,YMatrix1);     
            plot(app.UIAxes,YMatrix2);
            plot(app.UIAxes,YMatrix3);
           
            % Create ylabel
            ylabel(app.UIAxes,'Torques (N*m)');
            
            % Create xlabel
            xlabel(app.UIAxes,'Time (ms)');  
             
            % Create legend
            legend(app.UIAxes,'Ankle','Knee','Hip');

        end

        % Button pushed function: PlotExoskeletonJointAnglesButton_2
        function PlotExoskeletonJointAnglesButton_2Pushed(app, event)
            cla(app.UIAxes);
            app.Label_4.Text = ' ';
            a = get_param('Human_ActiveExo/HUMAN + EXOSKELETON/EXOSKELETON/To File2','commented');
            b = get_param('Human_ActiveExo/HUMAN + EXOSKELETON/EXOSKELETON/To File6','commented');
            c = get_param('Human_ActiveExo/HUMAN + EXOSKELETON/EXOSKELETON/To File5','commented');
            d = get_param('Human_ActiveExo/HUMAN + EXOSKELETON/Lower Body/To File6', 'Commented');
            
            if strcmp(a,'off') && strcmp(d,'on')
                load('Exo Positions\ExoskeletonPositionsNom.mat')
                EP = ExoskeletonPositionsNom;
                title(app.UIAxes,'Exo Anglular Position, Nominal Size Human',...
                 'FontWeight','bold');
            elseif strcmp(b,'off') && strcmp(d,'on')
                load('Exo Positions\ExoskeletonPositionsP15.mat')
                EP = ExoskeletonPositionsP15;
                title(app.UIAxes,'Exo Anglular Position, +15% size Human',...
                 'FontWeight','bold');
            elseif strcmp(c,'off') && strcmp(d,'on')
                load('Exo Positions\ExoskeletonPositionsM15.mat')
                EP = ExoskeletonPositionsM15;
                title(app.UIAxes,'Exo Anglular Position, -15% size Human',...
                 'FontWeight','bold');
            elseif strcmp(d,'off')
                app.Label_4.Text = 'No Exo Attached';
                return;
            end
            
            
            YMatrix1 = [EP(2,:)];
            YMatrix2 = [EP(3,:)];
            YMatrix3 = [EP(4,:)];
            
            %% Human Model Ankle Torques Vs Mak

            plot(app.UIAxes,-YMatrix1);     
            plot(app.UIAxes,-YMatrix2);
            plot(app.UIAxes,YMatrix3);
           
            % Create ylabel
            ylabel(app.UIAxes, 'Position (Deg)');
            
            % Create xlabel
            xlabel(app.UIAxes,'Time (ms)');  
            
            %Create title
               
             
            % Create legend
            legend(app.UIAxes,'Ankle','Knee','Hip');
        end

        % Button pushed function: 
        % PlotExoskeletonJointTorquesButton_2
        function PlotExoskeletonJointTorquesButton_2Pushed(app, event)
            cla(app.UIAxes);
            app.Label_4.Text = ' ';
            
            a = get_param('Human_ActiveExo/HUMAN + EXOSKELETON/Lower Body/To File1','commented');
            b = get_param('Human_ActiveExo/HUMAN + EXOSKELETON/Lower Body/To File2','commented');
            c = get_param('Human_ActiveExo/HUMAN + EXOSKELETON/Lower Body/To File6', 'Commented');
            
            if strcmp(a,'off')
                load('Exo Torques\ExoskeletonTorques60.mat')
                ET = ExoskeletonTorques60;
                title(app.UIAxes,'Exoskeleton 60% Assist Actuator Torques',...
                 'FontWeight','bold');
            elseif strcmp(b,'off')
                load('Exo Torques\ExoskeletonTorques30.mat')
                ET = ExoskeletonTorques30;
                title(app.UIAxes,'Exoskeleton 30% Assist Actuator Torques',...
                 'FontWeight','bold');
            elseif strcmp(c,'off')
                app.Label_4.Text = 'No Exo Attached';
                return;   
            end
            
            
            YMatrix1 = [ET(2,:)];
            YMatrix2 = [ET(3,:)];
            YMatrix3 = [ET(4,:)];
            
            %% Human Model Ankle Torques Vs Mak

            plot(app.UIAxes,-YMatrix1);     
            plot(app.UIAxes,-YMatrix2);
            plot(app.UIAxes,YMatrix3);
           
            % Create ylabel
            ylabel(app.UIAxes, 'Toruqes (N*m)');
            
            % Create xlabel
            xlabel(app.UIAxes,'Time (ms)');  
            
            %Create title
               
             
            % Create legend
            legend(app.UIAxes,'Ankle','Knee','Hip');
        end

        % Button pushed function: PlotExoHumanContactForcesButton
        function PlotExoHumanContactForcesButtonPushed(app, event)
            cla(app.UIAxes);
            app.Label_4.Text = ' ';
            
            a = get_param('Human_ActiveExo/HUMAN + EXOSKELETON/EXOSKELETON/To File2','commented');
            b = get_param('Human_ActiveExo/HUMAN + EXOSKELETON/EXOSKELETON/To File6','commented');
            c = get_param('Human_ActiveExo/HUMAN + EXOSKELETON/EXOSKELETON/To File5','commented');
            d = get_param('Human_ActiveExo/HUMAN + EXOSKELETON/Lower Body/To File1','commented');
            e = get_param('Human_ActiveExo/HUMAN + EXOSKELETON/Lower Body/To File2','commented');
            f = get_param('Human_ActiveExo/HUMAN + EXOSKELETON/Lower Body/To File6','commented');
             
            if strcmp(d,'off')
                load('Exo-Human Contacts\ForceContact60.mat')
                CF = ForceContact60;
                if strcmp(a,'off')
                    title(app.UIAxes,'Exo Contact Force, Nominal Size, 60% Assist Human',...
                    'FontWeight','bold'); 
                elseif strcmp(b,'off')
                    title(app.UIAxes,'Exo Contact Force, +15% Size, 60% Assist Human',...
                    'FontWeight','bold');
                elseif strcmp(c,'off')
                    title(app.UIAxes,'Exo Contact Force, -15% Size, 60% Assist Human',...
                    'FontWeight','bold');
                end
            elseif strcmp(e,'off')
                load('Exo-Human Contacts\ForceContact30.mat')
                CF = ForceContact30;
                if strcmp(a,'off')
                    title(app.UIAxes,'Exo Contact Force, Nominal Size, 30% Assist Human',...
                    'FontWeight','bold'); 
                elseif strcmp(b,'off')
                    title(app.UIAxes,'Exo Contact Force, +15% Size, 30% Assist Human',...
                    'FontWeight','bold');
                elseif strcmp(c,'off')
                    title(app.UIAxes,'Exo Contact Force, -15% Size, 30% Assist Human',...
                    'FontWeight','bold');
                end   
            elseif strcmp(f,'off')
                app.Label_4.Text = 'No Exo Attached';
                return;    
            end
            
            
            YMatrix1 = [CF(2,:)];
            YMatrix2 = [CF(5,:)];
            YMatrix3 = [CF(8,:)];
            YMatrix4 = [CF(11,:)];
                
            
            %% Human Model Ankle Torques Vs Mak

            plot(app.UIAxes,YMatrix1);     
            plot(app.UIAxes,YMatrix2);
            plot(app.UIAxes,YMatrix3);
            plot(app.UIAxes,YMatrix4);
            % Create ylabel
            ylabel(app.UIAxes, 'Forces (N*m)');
            
            % Create xlabel
            xlabel(app.UIAxes,'Time (ms)');  
            
            % Create legend
            legend(app.UIAxes,'Pelvis','Thigh','Shank','Foot');
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            save_system("Human_ActiveExo.slx","Human-Exo Simulink Files\Human_ActiveExo.slx");
            close_system;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftPanel.Title = 'VARIABLES';
            app.SELECTHUMANSIZELabel = uilabel(app.LeftPanel);
            app.SELECTHUMANSIZELabel.Text = '1. SELECT HUMAN SIZE';
            app.kgLabel = uilabel(app.LeftPanel);
            app.kgLabel.Text = '68.1kg';
            app.mLabel = uilabel(app.LeftPanel);
            app.mLabel.Text = '1.94m';
            app.kgLabel_2 = uilabel(app.LeftPanel);
            app.kgLabel_2.Text = '59.2kg';
            app.kgLabel_3 = uilabel(app.LeftPanel);
            app.kgLabel_3.Text = '50.3kg';
            app.mLabel_2 = uilabel(app.LeftPanel);
            app.mLabel_2.Text = '1.43m';
            app.mLabel_3 = uilabel(app.LeftPanel);
            app.mLabel_3.Text = '1.69m';
            app.Button = uibutton(app.LeftPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '-15%';
            app.Button_2 = uibutton(app.LeftPanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '+15%';
            app.NominalButton = uibutton(app.LeftPanel, 'push');
            app.NominalButton.ButtonPushedFcn = createCallbackFcn(app, @NominalButtonPushed, true);
            app.NominalButton.Text = 'Nominal';
            app.DETACHButton = uibutton(app.LeftPanel, 'push');
            app.DETACHButton.ButtonPushedFcn = createCallbackFcn(app, @DETACHButtonPushed, true);
            app.DETACHButton.Text = 'DETACH';
            app.ATTACHButton = uibutton(app.LeftPanel, 'push');
            app.ATTACHButton.ButtonPushedFcn = createCallbackFcn(app, @ATTACHButtonPushed, true);
            app.ATTACHButton.Text = 'ATTACH';
            app.ATTACHDETACHEXOSKELETONLabel = uilabel(app.LeftPanel);
            app.ATTACHDETACHEXOSKELETONLabel.Text = '2. ATTACH/DETACH EXOSKELETON';
            app.PERCENTAGETORQUEREDUCTIONLabel = uilabel(app.LeftPanel);
            app.PERCENTAGETORQUEREDUCTIONLabel.Text = '3. PERCENTAGE TORQUE REDUCTION';
            app.Button_3 = uibutton(app.LeftPanel, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '30%';
            app.Button_4 = uibutton(app.LeftPanel, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '60%';
            app.ThismaytakesometimeLabel = uilabel(app.LeftPanel);
            app.ThismaytakesometimeLabel.Text = '(This may take some time)';
            app.RunSimulationButton = uibutton(app.LeftPanel, 'push');
            app.RunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
            app.RunSimulationButton.Text = 'Run Simulation';
            app.RUNLabel = uilabel(app.LeftPanel);
            app.RUNLabel.Text = '4. RUN';
            app.CenterPanel = uipanel(app.GridLayout);
            app.SimulationStoppedLampLabel = uilabel(app.CenterPanel);
            app.SimulationStoppedLampLabel.Text = 'Simulation Stopped';
            app.SimulationStoppedLamp = uilamp(app.CenterPanel);
            app.STOPButton = uibutton(app.CenterPanel, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.Label = uilabel(app.CenterPanel);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.CenterPanel);
            app.Label_2.Text = '';
            app.Label_3 = uilabel(app.CenterPanel);
            app.Label_3.Text = '';
            app.Label_4 = uilabel(app.CenterPanel);
            app.Label_4.Text = '';
            app.SELECTEDVARIABLESLabel = uilabel(app.CenterPanel);
            app.SELECTEDVARIABLESLabel.Text = 'SELECTED VARIABLES';
            app.UIAxes = uiaxes(app.CenterPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.RightPanel = uipanel(app.GridLayout);
            app.RightPanel.Title = 'GRAPHS';
            app.PlotExoskeletonJointAnglesButton_2 = uibutton(app.RightPanel, 'push');
            app.PlotExoskeletonJointAnglesButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlotExoskeletonJointAnglesButton_2Pushed, true);
            app.PlotExoskeletonJointAnglesButton_2.Text = 'Plot Exoskeleton Joint Angles';
            app.PlotExoskeletonJointTorquesButton_2 = uibutton(app.RightPanel, 'push');
            app.PlotExoskeletonJointTorquesButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlotExoskeletonJointTorquesButton_2Pushed, true);
            app.PlotExoskeletonJointTorquesButton_2.Text = 'Plot Exoskeleton Joint Torques';
            app.PlotHumanJointTorquesButton = uibutton(app.RightPanel, 'push');
            app.PlotHumanJointTorquesButton.ButtonPushedFcn = createCallbackFcn(app, @PlotHumanJointTorquesButtonPushed, true);
            app.PlotHumanJointTorquesButton.Text = 'Plot Human Joint Torques';
            app.PlotHumanJointAnglesButton = uibutton(app.RightPanel, 'push');
            app.PlotHumanJointAnglesButton.ButtonPushedFcn = createCallbackFcn(app, @PlotHumanJointAnglesButtonPushed, true);
            app.PlotHumanJointAnglesButton.Text = 'Plot Human Joint Angles';
            app.PlotExoHumanContactForcesButton = uibutton(app.RightPanel, 'push');
            app.PlotExoHumanContactForcesButton.ButtonPushedFcn = createCallbackFcn(app, @PlotExoHumanContactForcesButtonPushed, true);
            app.PlotExoHumanContactForcesButton.Text = 'Plot Exo-Human Contact Forces';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LLE_SIM

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end